classdef RobotControlApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RoboticArmGUIUIFigure     matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        GoHomeButton              matlab.ui.control.Button
        SetWritingPlaneButton     matlab.ui.control.Button
        LettersEditFieldLabel     matlab.ui.control.Label
        LettersEditField          matlab.ui.control.EditField
        WriteLettersButton        matlab.ui.control.Button
        GenerateTrajectoryButton  matlab.ui.control.Button
        AnimateTrajectoryButton   matlab.ui.control.Button
        OpenCloseGripperButton    matlab.ui.control.Button
        OpenGripperButton         matlab.ui.control.StateButton
        ClosedLamp                matlab.ui.control.Lamp
        OpenLamp                  matlab.ui.control.Lamp
        GripperLabel              matlab.ui.control.Label
        SerialPortLabel           matlab.ui.control.Label
        SerialPortEditField       matlab.ui.control.EditField
        WritingPlaneLamp_Green    matlab.ui.control.Lamp
        WritingPlaneLamp_Red      matlab.ui.control.Lamp
        WritingPlaneLabel         matlab.ui.control.Label
        TrajectoryLabel           matlab.ui.control.Label
        TrajectoryLamp_Green      matlab.ui.control.Lamp
        TrajectoryLamp_Red        matlab.ui.control.Lamp
        Label                     matlab.ui.control.Label
        LettersLabel              matlab.ui.control.Label
        LettersLamp_Green         matlab.ui.control.Lamp
        LettersLamp_Red           matlab.ui.control.Lamp
        MessageEditFieldLabel     matlab.ui.control.Label
        MessageEditField          matlab.ui.control.EditField
        ActionsLabel              matlab.ui.control.Label
        StatusLabel               matlab.ui.control.Label
        SettingsLabel             matlab.ui.control.Label
        PlaneEditFieldLabel       matlab.ui.control.Label
        PlaneEditField            matlab.ui.control.EditField
    end

    
    properties (Access = private)
        
        % Define the link lengths of the robot.
        rs = [1 + 7/16, 4 + 1/16, 2 + 7/16, 3 + 1/4, 3 + 1/8, 0, 5 + 5/8];
        
        % Define the home orientation.
        M
        
        % Define the screw axes.
        S
        
        %Set the default letter.
        Ltr = 'PSU';
        
        % Define the array of possible letters.
        Ltrs = {'A' 'B' 'C' 'D' 'E' 'F' 'G' 'H' 'I' 'J' 'K' 'L' 'M' 'N' 'O' 'P' 'Q' 'R' 'S' 'T' 'U' 'V' 'W' 'X' 'Y' 'Z'};
        
        % Define the Motor ID numbers.
        MotorIDs = (1:5)';
        
        % Define the rotational and translational tolerances to use for the Newton-Raphson approximation of the inverse kinematics.
        eomg = 1;
        ev = 1e-2;
        
        % Define the maximum displacement in a single step.
        dtol_ltr = 1/8;
        dtol_travel = 1/4;
        
        % Set the making plots option to false.  Some functions make a lot of different plots for reference and debugging, however it isn't always useful to have many plots appearing.
        bMakePlots = false;
        
        % Set whether to animate the letter trajectory.
        bAnimateLetterTrajectory = true;
        
        % Set whether to create the motor plots.
        bPlotMotorGraphs = false;
        
        % Set the gripper to be closed by default.
        bGripperClosed = true;
        
        % Define the movement tolerance.
        move_threshold = 0.25;                            %Defines the threshold at which xbox controller axis data will be interpreted as being an intentional movement (and not just random noise).
        
        % Set the free movement sensitivity.
        move_sensitivity = 4;                       %This is the number of decimal points to which to round xbox controller axis data.  The higher the number, the more sensitive.  This makes the system more susceptible to noise.
        
        % Set the free movement size. Increasing this number makes the robot move farther in a single step.
        move_size = 1;                             %Defines the percentage of the maximum single displacement step to travel when an axis is fully engaged.
        
        % Set the movement speed.
        move_speed = 0.1;
        
        % Define the number of read attempts to use. SHOULD BE AN ODD INTEGER.
        NumReadAttempts = 3;            %Increasing this number makes it less likely that an incorrect read position will occur, but makes each read attempt take longer.
        
        % Set the assumed starting angle.
        thetac = [pi/4 -pi/2 0 0 -pi/4]' + pi/6;
        thetas_user = [pi/4 -pi/2 0 0 -pi/4]';
        theta_home = [pi/4 -pi/2 0 0 -pi/4]';
        
        % Define the Default Plane Points.
        Pts_Plane = [-0.3591   -1.8378    2.1023;
                     12.1268   10.2167   10.0760;
                     0.2205    0.0932    0.0295];
        
        %Compute the transformation matrix associated with the defined plane.  i.e., the transformation matrix that maps from the template letter space in the xy-plane at the origin to the location of the newly defined plane in space.
        T_Plane
        
        % Define the current trajectory.
        thetas          % [-] Current trajectory in radians.
        mpos            % [#] Current trajectory in motor position integers.
        
        % Set a flag variable that determines whether the current target letters are valid.
        bValidLetters = true;
        
        % Create a property to store the serial port.
        serial_port
        
    end
    
    methods (Access = private)
        
        % Implement a function to set the target letters.
        function SetTargetLetter( app )
            
            % This function requests that the user enter the target letter that will be drawn later.  It takes in the valid letter options, and returns the option entered by the user.
            
            % Print that we are defining which letter to write.
            fprintf('\n\nCHECKING LETTER VALIDITY...\n')
            
            % Request the user type a letter on the keyboard.
            app.Ltr = app.LettersEditField.Value;
            
            % Check whether the user supplied word is valid.
            app.bValidLetters = TestWordValidity( app );

            % Validate the user input by comparing it to the array of possible letters defined during the setup.
            if app.bValidLetters                                       % If the desired letter combination is valid...
                
                %Print that the letter is accepted.
                fprintf('\nDone: Valid Letter Confirmed.\n')
                
            else                                                      % Otherwise... (The desired letter combination is invalid.)
                
                %State that the character is not valid and display valid letters.
                fprintf('\nInvalid letter. The following letters are valid:')
                disp(app.Ltrs)
                fprintf('Try Again.\n\n')
                
            end


        end
        
        % Implement a function to assess the validity of a proposed string of letters.
        function [ bValid ] = TestWordValidity( app )
        
            % This function takes in a user supplied list of letters can assess whether all of the letters are valid.  If all of the letters are valid, it returns bValid = true.  Otherwise, it returns bValid = false.
            
            % Set the number of letter matches to start at zero.
            bMatches = 0;
            
            % Compute the total number of valid letters in the list of user supplied letters.
            for k = 1:length(app.Ltr)                                                  % Iterate through all of the user supplied letters...
                % Add up the total number of valid letters in the user supplied list of letters.
                bMatches = bMatches + (sum(app.Ltr(k) == char(app.Ltrs)) == 1);
            end
            
            % Determine whether all of the user supplied letters are valid.
            if bMatches == length(app.Ltr)          % If all of the user supplied letters are valid...
               bValid = true;                       % Set the bValid flag to true.
            else                                    % Otherwise...
                bValid = false;                     % Set the bValid flag to false.
            end
        
        end
    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Open the serial port.
%             app.serial_port = OpenSerialPort( 'COM3', 115200 );
            app.serial_port = OpenSerialPort( app.SerialPortEditField.Value, 115200 );

            % Define the home orientation of each joint.  To compute the appropriate joint angles, we only need the home orientation of the end effector.  However, in order to plot the movement of all of the joints, we need all home orientations.
            M1 = [1 0 0 0; 0 1 0 0; 0 0 1 app.rs(1); 0 0 0 1];
            M2 = [1 0 0 0; 0 1 0 0; 0 0 1 app.rs(1) + app.rs(2); 0 0 0 1];
            M3 = [1 0 0 0; 0 1 0 0; 0 0 1 app.rs(1) + app.rs(2) + app.rs(3); 0 0 0 1];
            M4 = [1 0 0 0; 0 1 0 0; 0 0 1 app.rs(1) + app.rs(2) + app.rs(3) + app.rs(4); 0 0 0 1];
            M5 = [1 0 0 0; 0 1 0 0; 0 0 1 app.rs(1) + app.rs(2) + app.rs(3) + app.rs(4) + app.rs(5); 0 0 0 1];
            M6 = [1 0 0 0; 0 1 0 0; 0 0 1 app.rs(1) + app.rs(2) + app.rs(3) + app.rs(4) + app.rs(5) + app.rs(6); 0 0 0 1];
            M7 = [1 0 0 0; 0 1 0 -(1 + 5/8); 0 0 1 app.rs(1) + app.rs(2) + app.rs(3) + app.rs(4) + app.rs(5) + app.rs(6) + app.rs(7); 0 0 0 1];
            
            % Store the joint orientations in a multidimensional matrix.  Each layer corresponds to one of the joint home positions.
            app.M = cat(3, M1, M2, M3, M4, M5, M6, M7);
            
            % Define the screw axes.
            S1 = [1 0 0 0 app.rs(1) 0]';
            S2 = [1 0 0 0 app.rs(1) + app.rs(2) 0]';
            S3 = [0 1 0 -(app.rs(1) + app.rs(2) + app.rs(3)) 0 0]';
            S4 = [0 1 0 -(app.rs(1) + app.rs(2) + app.rs(3) + app.rs(4)) 0 0]';
            S5 = [1 0 0 0 (app.rs(1) + app.rs(2) + app.rs(3) + app.rs(4) + app.rs(5)) 0]';
            S6 = [1 0 0 0 (app.rs(1) + app.rs(2) + app.rs(3) + app.rs(4) + app.rs(5) + app.rs(6)) 0]';
            
            % Store the screw axes in a matrix.  Each column of S is a screw axis for a different joint.
            app.S = [S1 S2 S3 S4 S5];
            
            %Compute the transformation matrix associated with the defined plane.  i.e., the transformation matrix that maps from the template letter space in the xy-plane at the origin to the location of the newly defined plane in space.
            app.T_Plane = GetPlaneTransformationMatrix( app.Pts_Plane, [0 1 0]' );
            
        end

        % Button pushed function: GenerateTrajectoryButton
        function GenerateTrajectoryButtonPushed(app, event)
            
            % Determine the current target letters.
            SetTargetLetter( app )
            
            % Retrieve the current orientation of the end effector.
            Tc = FKinSpace(app.M(:, :, end), app.S, app.thetac);
                        
            % State that letter trajectories will now be generated.
            fprintf('\nGENERATING TRAJECTORY. Please Wait...\n\n')
            
            % State which letter is being processed.
            fprintf(['Generating ', app.Ltr, ' Trajectory...\n'])
            
            % Generate the letter trajectories.
            [ app.thetas, app.mpos ] = PlanLetterTrajectoryWithTravel( app.S, app.M, Tc, app.T_Plane, app.Pts_Plane, app.Ltr, app.thetac, app.eomg, app.ev, app.dtol_ltr, app.dtol_travel, app.bMakePlots );
            
            %Print that we are finished generating the letter trajectories.
            fprintf('\nDone: Letter Trajectories Confirmed.\n\n')
            
        end

        % Button pushed function: GoHomeButton
        function GoHomeButtonPushed(app, event)
            
            % Set the motor to the home position.
            SetMotorPosition3( app.serial_port, app.MotorIDs, Rad2MotorPos( app.theta_home ), Rad2MotorPos( app.thetac ), 0.1, app.bPlotMotorGraphs );
            
            % Reset the default motor angles.
            [app.thetac, app.thetas_user] = deal( app.theta_home );    
            
        end

        % Close request function: RoboticArmGUIUIFigure
        function RoboticArmGUIUIFigureCloseRequest(app, event)
            
            % Close the serial port.
            CloseSerialPort( app.serial_port ) 
            
            % Delete the app.
            delete(app)
            
        end

        % Button pushed function: AnimateTrajectoryButton
        function AnimateTrajectoryButtonPushed(app, event)
            
            
            %% NEED TO ADD ANIMATE TRAJECTORY CODE.
            
            
        end

        % Button pushed function: WriteLettersButton
        function WriteLettersButtonPushed(app, event)
            
            % Send the trajectory to the motors.
            SetMotorPosition3( app.serial_port, app.MotorIDs, app.mpos, Rad2MotorPos( app.thetac ), app.move_speed, app.bPlotMotorGraphs );
           
            % Update the current motor angles variable.
            app.thetac = app.thetas(:, end);
            
        end

        % Button pushed function: OpenCloseGripperButton
        function OpenCloseGripperButtonPushed(app, event)
            
             % Determine whether to open or closed the gripper.
            if app.bGripperClosed                    % If the gripper is closed...
                
                % Define the open gripper position.
                theta_Gripper = app.thetac; theta_Gripper(end) = -3*pi/4;
                
                % Update the flag to state that the gripper is open.
                app.bGripperClosed = false;
                
            else                                 % If the gripper is open...
                
                % Define the closed gripper position.
                theta_Gripper = app.thetac; theta_Gripper(end) = app.theta_home(end);
                
                % Update the flag to state that the gripper is closed.
                app.bGripperClosed = true;
                
            end
            
            % Move the gripper.
            SetMotorPosition3( app.serial_port, app.MotorIDs, Rad2MotorPos( theta_Gripper ), Rad2MotorPos( app.thetac ), 0.1, app.bPlotMotorGraphs );
            
            % Update the current joint angles variable.
            app.thetac = theta_Gripper;            
            
        end

        % Value changed function: OpenGripperButton
        function OpenGripperButtonValueChanged(app, event)
            
            % Determine whether to open or closed the gripper.
            if (app.bGripperClosed) && (app.OpenGripperButton.Value == 1)                    % If the gripper is closed and we want it open...
                
                % Define the open gripper position.
                theta_Gripper = app.thetac; theta_Gripper(end) = -3*pi/4;
                
                % Update the flag to state that the gripper is open.
                app.bGripperClosed = false;
                
                % Update the associated GUI lamps.
                app.ClosedLamp.Enable = 'off';
                app.OpenLamp.Enable = 'on';
                
            elseif (~app.bGripperClosed) && (app.OpenGripperButton.Value == 0)              % If the gripper is open and we want it closed...
                
                % Define the closed gripper position.
                theta_Gripper = app.thetac; theta_Gripper(end) = app.theta_home(end);
                
                % Update the flag to state that the gripper is closed.
                app.bGripperClosed = true;
                
                % Update the associated GUI lamps.
                app.ClosedLamp.Enable = 'on';
                app.OpenLamp.Enable = 'off';
                
            end
            
            % Move the gripper.
            SetMotorPosition3( app.serial_port, app.MotorIDs, Rad2MotorPos( theta_Gripper ), Rad2MotorPos( app.thetac ), 0.1, app.bPlotMotorGraphs );
            
            % Update the current joint angles variable.
            app.thetac = theta_Gripper;        
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RoboticArmGUIUIFigure = uifigure('Visible', 'off');
            app.RoboticArmGUIUIFigure.Name = 'Robotic Arm GUI';
            app.RoboticArmGUIUIFigure.CloseRequestFcn = createCallbackFcn(app, @RoboticArmGUIUIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.RoboticArmGUIUIFigure);
            title(app.UIAxes, 'Robot Animation')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.ZTickLabel = '';
            app.GoHomeButton = uibutton(app.RoboticArmGUIUIFigure, 'push');
            app.GoHomeButton.ButtonPushedFcn = createCallbackFcn(app, @GoHomeButtonPushed, true);
            app.GoHomeButton.Tooltip = {'Sends the robot to its home position.'};
            app.GoHomeButton.Text = 'Go Home';
            app.SetWritingPlaneButton = uibutton(app.RoboticArmGUIUIFigure, 'push');
            app.SetWritingPlaneButton.Tooltip = {'Initiates an interactive procedure for calibrating the writing plane of the robot.'};
            app.SetWritingPlaneButton.Text = 'Set Writing Plane';
            app.LettersEditFieldLabel = uilabel(app.RoboticArmGUIUIFigure);
            app.LettersEditFieldLabel.Text = 'Letters:';
            app.LettersEditField = uieditfield(app.RoboticArmGUIUIFigure, 'text');
            app.LettersEditField.Value = 'PSU';
            app.WriteLettersButton = uibutton(app.RoboticArmGUIUIFigure, 'push');
            app.WriteLettersButton.ButtonPushedFcn = createCallbackFcn(app, @WriteLettersButtonPushed, true);
            app.WriteLettersButton.Tooltip = {'Writes the user specified letters on the current writing plane using the most recently generated trajectory.'};
            app.WriteLettersButton.Text = 'Write Letters';
            app.GenerateTrajectoryButton = uibutton(app.RoboticArmGUIUIFigure, 'push');
            app.GenerateTrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateTrajectoryButtonPushed, true);
            app.GenerateTrajectoryButton.Tooltip = {'Generates the trajectory to follow in order to write the desired letters.'};
            app.GenerateTrajectoryButton.Text = 'Generate Trajectory';
            app.AnimateTrajectoryButton = uibutton(app.RoboticArmGUIUIFigure, 'push');
            app.AnimateTrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @AnimateTrajectoryButtonPushed, true);
            app.AnimateTrajectoryButton.Tooltip = {'Animates the current'};
            app.AnimateTrajectoryButton.Text = 'Animate Trajectory';
            app.OpenCloseGripperButton = uibutton(app.RoboticArmGUIUIFigure, 'push');
            app.OpenCloseGripperButton.ButtonPushedFcn = createCallbackFcn(app, @OpenCloseGripperButtonPushed, true);
            app.OpenCloseGripperButton.Enable = 'off';
            app.OpenCloseGripperButton.Text = 'Open / Close Gripper';
            app.OpenGripperButton = uibutton(app.RoboticArmGUIUIFigure, 'state');
            app.OpenGripperButton.ValueChangedFcn = createCallbackFcn(app, @OpenGripperButtonValueChanged, true);
            app.OpenGripperButton.Tooltip = {'Opens the gripper when depressed, closed the gripper raised.'};
            app.OpenGripperButton.Text = {'Open Gripper'; ''};
            app.ClosedLamp = uilamp(app.RoboticArmGUIUIFigure);
            app.OpenLamp = uilamp(app.RoboticArmGUIUIFigure);
            app.OpenLamp.Enable = 'off';
            app.GripperLabel = uilabel(app.RoboticArmGUIUIFigure);
            app.GripperLabel.Text = 'Gripper';
            app.SerialPortLabel = uilabel(app.RoboticArmGUIUIFigure);
            app.SerialPortLabel.Text = 'Serial Port:';
            app.SerialPortEditField = uieditfield(app.RoboticArmGUIUIFigure, 'text');
            app.SerialPortEditField.Value = 'COM3';
            app.WritingPlaneLamp_Green = uilamp(app.RoboticArmGUIUIFigure);
            app.WritingPlaneLamp_Red = uilamp(app.RoboticArmGUIUIFigure);
            app.WritingPlaneLamp_Red.Enable = 'off';
            app.WritingPlaneLabel = uilabel(app.RoboticArmGUIUIFigure);
            app.WritingPlaneLabel.Text = 'Writing Plane';
            app.TrajectoryLabel = uilabel(app.RoboticArmGUIUIFigure);
            app.TrajectoryLabel.Text = 'Trajectory';
            app.TrajectoryLamp_Green = uilamp(app.RoboticArmGUIUIFigure);
            app.TrajectoryLamp_Red = uilamp(app.RoboticArmGUIUIFigure);
            app.TrajectoryLamp_Red.Enable = 'off';
            app.Label = uilabel(app.RoboticArmGUIUIFigure);
            app.Label.Text = '';
            app.LettersLabel = uilabel(app.RoboticArmGUIUIFigure);
            app.LettersLabel.Text = 'Letters';
            app.LettersLamp_Green = uilamp(app.RoboticArmGUIUIFigure);
            app.LettersLamp_Red = uilamp(app.RoboticArmGUIUIFigure);
            app.LettersLamp_Red.Enable = 'off';
            app.MessageEditFieldLabel = uilabel(app.RoboticArmGUIUIFigure);
            app.MessageEditFieldLabel.Text = 'Message:';
            app.MessageEditField = uieditfield(app.RoboticArmGUIUIFigure, 'text');
            app.MessageEditField.Editable = 'off';
            app.ActionsLabel = uilabel(app.RoboticArmGUIUIFigure);
            app.ActionsLabel.Text = 'Actions';
            app.StatusLabel = uilabel(app.RoboticArmGUIUIFigure);
            app.StatusLabel.Text = 'Status';
            app.SettingsLabel = uilabel(app.RoboticArmGUIUIFigure);
            app.SettingsLabel.Text = 'Settings';
            app.PlaneEditFieldLabel = uilabel(app.RoboticArmGUIUIFigure);
            app.PlaneEditFieldLabel.Text = 'Plane:';
            app.PlaneEditField = uieditfield(app.RoboticArmGUIUIFigure, 'text');
            % Show the figure after all components are created
            app.RoboticArmGUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RobotControlApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RoboticArmGUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RoboticArmGUIUIFigure)
        end
    end
end